classdef FiltrelemeMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        leriButton                   matlab.ui.control.Button
        FiltrelemeMensDropDown       matlab.ui.control.DropDown
        FiltrelemeMensDropDownLabel  matlab.ui.control.Label
        GeriButton                   matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        FiltrelemeUygulamaistemiyorumButton  matlab.ui.control.RadioButton
        FiltrelemeUygulamakistiyorumButton  matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: leriButton
        function leriButtonPushed(app, event)
            MorfolojikMenu
        end

        % Button pushed function: GeriButton
        function GeriButtonPushed(app, event)
            OnislemMenu2
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.FiltrelemeUygulamakistiyorumButton.Value==1
               app.FiltrelemeMensDropDown.Enable=true;
            elseif app.FiltrelemeUygulamaistemiyorumButton.Value==1
               app.FiltrelemeMensDropDown.Enable=false;
            end
            clc
            clear all 
            close all
        end

        % Value changed function: FiltrelemeMensDropDown
        function FiltrelemeMensDropDownValueChanged(app, event)
            value = app.FiltrelemeMensDropDown.Value;
            switch value
                case 'Gaussian Bulanıklaştırma filtresi '
                    img=skimage.io.imread('GriR.png');
                    [x y]=img.size()
                    m,n=mask.size()
                    new=zeros(x+m-1,y+n-1)
                    n=n/2
                    m=m/2
                    
                case 'Keskinleştirme filtresi'
                    
                case 'Kenar bulma filtresi'
                    x=imread('GriR.png');
                    d=double(x);
                    for i=1:size(d,1)-2
                        for j=1:size(d,2)-2
                            %sobel mask for x-direction
                            Gx=((2*d(i+2,j+1)+d(i+2,j)+d(i+2,j+2))-(2*d(i,j+1)+d(i,j)+d(i,j+2)));
                            %sobel mask y-direction
                            Gy=((2*d(i+1,j+2)+d(i,j+2)+d(i+2,j+2))-(2*d(i+1,j)+d(i,j)+d(i+2,j)));
                            %the gradient of the image
                            %X(i,j)=abs(Gx)+abs(Gy);
                            x(i,j)=sqrt(Gx.^2+Gy.^2);
                        end
                    end
                    app.UIAxes.Visible=true;
                    imshow(x,'Parent',app.UIAxes);
                    imwrite(x,'KenarBF.png');
                case 'Ortalama (Mean) filtresi'
                    x=imread('GriR.png');
                    filter=ones(3);% 3x3 matris 1 lerden oluşuyr
                    filter=ones(3)/sum(filter(:));
                    filter=double(filter);
                    m=padarray(x,[1 1]);
                    m=double(m);
                    %pre-allocate the matrix
                    for i=1:size(m,1)-2
                        for j=1:size(m,2)-2
                            temp=m(i:i+2,j:j+2).*filter;
                            output(i,j)=sum(temp(:));
                        end
                    end
                    sonuc=uint8(output)
                    app.UIAxes.Visible=true;
                    imshow(sonuc,'Parent',app.UIAxes);
                    imwrite(sonuc,'OrtalamaF.png');
                case 'Ortanca (Median) filtresi'
                    x=imread("GriR.png");
                    I=imnoise(x,'salt & pepper',0.05);
                    MS=ones(3),
                    output=zeros(size(I)-2);
                    for i=1:size(I,1)-2
                        for j=2:size(I,2)-2
                            temp=double(I(i:i+2,j:j+2)).*MS,
                            output(i,j)=median(temp(:));
                        end
                    end
                    sonuc=uint8(output)
                    app.UIAxes.Visible=true;
                    imshow(sonuc,'Parent',app.UIAxes);
                    imwrite(sonuc,'OrtancaF.png');
                case 'Kontra Harmonik Ortalama filtresi'
                    
            end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FiltrelemeUygulamakistiyorumButton = uiradiobutton(app.ButtonGroup);
            app.FiltrelemeUygulamakistiyorumButton.Text = 'Filtreleme Uygulamak istiyorum';
            app.FiltrelemeUygulamaistemiyorumButton = uiradiobutton(app.ButtonGroup);
            app.FiltrelemeUygulamaistemiyorumButton.Text = 'Filtreleme Uygulama istemiyorum';
            app.FiltrelemeUygulamaistemiyorumButton.Value = true;
            app.GeriButton = uibutton(app.UIFigure, 'push');
            app.GeriButton.ButtonPushedFcn = createCallbackFcn(app, @GeriButtonPushed, true);
            app.GeriButton.Text = 'Geri';
            app.FiltrelemeMensDropDownLabel = uilabel(app.UIFigure);
            app.FiltrelemeMensDropDownLabel.Text = 'Filtreleme Menüsü';
            app.FiltrelemeMensDropDown = uidropdown(app.UIFigure);
            app.FiltrelemeMensDropDown.Items = {'Gaussian Bulanıklaştırma filtresi', 'Keskinleştirme filtresi', 'Kenar bulma filtresi', 'Ortalama (Mean) filtresi', 'Ortanca (Median) filtresi', 'Kontra Harmonik Ortalama filtresi'};
            app.FiltrelemeMensDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrelemeMensDropDownValueChanged, true);
            app.FiltrelemeMensDropDown.Enable = 'off';
            app.FiltrelemeMensDropDown.Value = 'Gaussian Bulanıklaştırma filtresi';
            app.leriButton = uibutton(app.UIFigure, 'push');
            app.leriButton.ButtonPushedFcn = createCallbackFcn(app, @leriButtonPushed, true);
            app.leriButton.Text = 'İleri';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiltrelemeMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end