classdef Kaydetme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResmiKaydetButton      matlab.ui.control.Button
        HangiformattakaydetmekistersinLabel  matlab.ui.control.Label
        GeriButton             matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        FormatSeDropDown       matlab.ui.control.DropDown
        FormatSeDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeriButton
        function GeriButtonPushed(app, event)
            MorfolojikMenu
        end

        % Button pushed function: ResmiKaydetButton
        function ResmiKaydetButtonPushed(app, event)
            x=imread('OrtancaF.png')
            app.Image.Visible=true
            if app.FormatSeDropDown.Value=='JPG'
                imwrite(x,'Son_Kaydedilen_Resim.jpg')
            end
            if app.FormatSeDropDown.Value=='BMP'
                imwrite(x,'Son_Kaydedilen_Resim.bmp')
            end
            if app.FormatSeDropDown.Value=='PNG'
                imwrite(x,'Son_Kaydedilen_Resim.png')
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FormatSeDropDownLabel = uilabel(app.UIFigure);
            app.FormatSeDropDownLabel.Text = 'Format Seç';
            app.FormatSeDropDown = uidropdown(app.UIFigure);
            app.FormatSeDropDown.Items = {'JPG', 'BMP', 'PNG'};
            app.FormatSeDropDown.Value = 'JPG';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'IskeletCR.png';
            app.GeriButton = uibutton(app.UIFigure, 'push');
            app.GeriButton.ButtonPushedFcn = createCallbackFcn(app, @GeriButtonPushed, true);
            app.GeriButton.Text = 'Geri';
            app.HangiformattakaydetmekistersinLabel = uilabel(app.UIFigure);
            app.HangiformattakaydetmekistersinLabel.Text = 'Hangi formatta kaydetmek istersin?';
            app.ResmiKaydetButton = uibutton(app.UIFigure, 'push');
            app.ResmiKaydetButton.ButtonPushedFcn = createCallbackFcn(app, @ResmiKaydetButtonPushed, true);
            app.ResmiKaydetButton.Text = 'Resmi Kaydet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kaydetme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end