classdef MorfolojikMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MorfolojikMensDropDown  matlab.ui.control.DropDown
        MorfolojikMensLabel     matlab.ui.control.Label
        leriButton              matlab.ui.control.Button
        GeriButton              matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        MorfolojikilemUygulamaistemiyorumButton  matlab.ui.control.RadioButton
        MorfolojikilemUygulamakistiyorumButton  matlab.ui.control.RadioButton
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: leriButton
        function leriButtonPushed(app, event)
            Kaydetme
        end

        % Button pushed function: GeriButton
        function GeriButtonPushed(app, event)
            FiltrelemeMenu
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.MorfolojikilemUygulamakistiyorumButton.Value==1
               app.MorfolojikMensDropDown.Enable=true;
            elseif app.MorfolojikilemUygulamaistemiyorumButton.Value==1
               app.MorfolojikMensDropDown.Enable=false;
            end
            clc
            clear all 
            close all
        end

        % Value changed function: MorfolojikMensDropDown
        function MorfolojikMensDropDownValueChanged(app, event)
            value = app.MorfolojikMensDropDown.Value;
            switch value
                case 'Siyah beyaz resimde genişletme'
                    A=imread('SiyahR.png');
                    %a=im2bw(A);
                    [r c]=size(A);
                    w=ones(3,3);
                    output1=[];
                    for i=2:1:r-1
                        for j=2:1:c-1
                            g=[w(1)*A(i-1,j-1) w(2)*A(i-1,j) w(3)*A(i-1,j+1)...
                            w(4)*A(i,j-1) w(5)*A(i,j) w(6)*A(i,j+1)...
                            w(7)*A(i+1,j-1) w(8)*A(i+1,j) w(9)*A(i+1,j+1)];
                            output1(i,j)=max(g);
                        end
                    end
                    app.UIAxes.Visible=true;
                    imshow(output1,'Parent',app.UIAxes);
                    imwrite(output1,'GenisletmeR.png');
                case 'Siyah beyaz resimde erozyon'
                    A=imread('SiyahR.png');
                    a=im2bw(A);
                    [r, c]=size(a);
                    w=ones(3,3);
                    output=[];
                    for i=2:1:r-1
                        for j=2:1:c-1
                            g=[w(1)*a(i-1,j-1) w(2)*a(i-1,j) w(3)*a(i-1,j+1)...
                            w(4)*a(i,j-1) w(5)*a(i,j) w(6)*a(i,j+1)...
                            w(7)*a(i+1,j-1) w(8)*a(i+1,j) w(9)*a(i+1,j+1)];
                            output(i,j)=min(g);
                        end
                    end
                    app.UIAxes.Visible=true;
                    imshow(output,'Parent',app.UIAxes);
                    imwrite(output,'Erosion.png');
                case 'İskelet çıkartma'
                    im = im2double(imread('GriR.png'));
                    [rows, cols] = size(im);
                    I = imbinarize(im); %gives binary of image
                    b = bwboundaries(I);
                    flag = 0;
                    T = 0;
                    for x = 2 : rows - 1
                        
                        for y = 2 : cols - 1
                            p1 = I(x,y);
                            p2 = I(x-1,y);
                            p3 = I(x+1,y-1);
                            p4 = I(x,y+1);
                            p5 = I(x+1,y+1);
                            p6 = I(x,y+1);
                            p7 = I(x-1,y+1);
                            p8 = I(x,y-1);
                            p9 = I(x-1,y-1);
                            N = p2 + p3 + p4 + p5 + p6 + p7 + p8 + p9;
                            if ((p2 == 0) && (p3 == 1))
                                T = T + 1;
                            end
                            if ((p3 == 0) && (p4 == 1))
                                T = T + 1;
                            end
                            if ((p4 == 0) && (p5 == 1))
                                T = T + 1;
                            end
                            if ((p5 == 0) && (p6 == 1))
                                T = T + 1;
                            end
                            if ((p6 == 0) && (p7 == 1))
                                T = T + 1;
                            end
                            if ((p7 == 0) && (p8 == 1))
                                T = T + 1;
                            end
                            if ((p8 == 0) && (p9 == 1))
                                T = T + 1;
                            end
                            if ((p9 == 0) && (p2 == 1))
                                T = T + 1;
                            end
                            if ((2 <= N) && (N <= 6) && (T == 1) && (p2*p4*p6 == 0) && (p4*p6*p8 == 0))
                                flag = p1;   
                                I(x,y) = flag;
                            else  
                                I(x,y) = p1;
                            end
                            if ((2 <= N) && (N <= 6) && (T == 1) && (p2*p4*p8 == 0) && (p2*p6*p8 == 0))
                                flag = p1;   
                                I(x,y) = flag;
                            else  
                                I(x,y) = p1;
                            end 
                            T = 0;
                            flag = 0;
                         end
                    end
                    app.UIAxes.Visible=true;
                    imshow(I,'Parent',app.UIAxes);
                    imwrite(I,'IskeletCR.png');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MorfolojikilemUygulamakistiyorumButton = uiradiobutton(app.ButtonGroup);
            app.MorfolojikilemUygulamakistiyorumButton.Text = 'Morfolojik işlem Uygulamak istiyorum';
            app.MorfolojikilemUygulamaistemiyorumButton = uiradiobutton(app.ButtonGroup);
            app.MorfolojikilemUygulamaistemiyorumButton.Text = 'Morfolojik işlem Uygulama istemiyorum';
            app.MorfolojikilemUygulamaistemiyorumButton.Value = true;
            app.GeriButton = uibutton(app.UIFigure, 'push');
            app.GeriButton.ButtonPushedFcn = createCallbackFcn(app, @GeriButtonPushed, true);
            app.GeriButton.Text = 'Geri';
            app.leriButton = uibutton(app.UIFigure, 'push');
            app.leriButton.ButtonPushedFcn = createCallbackFcn(app, @leriButtonPushed, true);
            app.leriButton.Text = 'İleri';
            app.MorfolojikMensLabel = uilabel(app.UIFigure);
            app.MorfolojikMensLabel.Text = 'Morfolojik İş. Menüsü';
            app.MorfolojikMensDropDown = uidropdown(app.UIFigure);
            app.MorfolojikMensDropDown.Items = {'Siyah beyaz resimde genişletme', 'Siyah beyaz resimde erozyon', 'İskelet çıkartma'};
            app.MorfolojikMensDropDown.ValueChangedFcn = createCallbackFcn(app, @MorfolojikMensDropDownValueChanged, true);
            app.MorfolojikMensDropDown.Enable = 'off';
            app.MorfolojikMensDropDown.Value = 'Siyah beyaz resimde genişletme';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorfolojikMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end