classdef OnislemMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIOnemislemM               matlab.ui.Figure
        ONLEMMENUSU1DropDown       matlab.ui.control.DropDown
        ONLEMMENUSU1DropDownLabel  matlab.ui.control.Label
        leriButton                 matlab.ui.control.Button
        GeriButton                 matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        OnislemeUygulama           matlab.ui.control.RadioButton
        OnislemUygula              matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ResimAcma)
            %app.callingapp=ResimAcma;
        end

        % Button pushed function: leriButton
        function leriButtonPushed(app, event)
            OnislemMenu2
            app.callingapp=OnislemMenu2;
        end

        % Button pushed function: GeriButton
        function GeriButtonPushed(app, event)
            ResimAcma
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.OnislemUygula.Value==1
               app.ONLEMMENUSU1DropDown.Enable=true;
            elseif app.OnislemeUygulama==1
               app.ONLEMMENUSU1DropDown.Enable=false;
            end
            clc
            clear all 
            close all
        end

        % Drop down opening function: ONLEMMENUSU1DropDown
        function ONLEMMENUSU1DropDownOpening(app, event)
          
        end

        % Value changed function: ONLEMMENUSU1DropDown
        function ONLEMMENUSU1DropDownValueChanged(app, event)
            value = app.ONLEMMENUSU1DropDown.Value;
            switch value
                   case 'Renkli resmi > Gri seviye resme dönüştürme'
                       image=imread('Resim.png');
                       %gri=im2gray(image);
                       %imshow(gri,'parent',app.UIAxes);
                       [r,g,b]=imsplit(im2double(image));
                       gri=0.2989 * r + 0.5870 * g + 0.1140 * b;
                       app.UIAxes.Visible=true;
                       imshow(gri,'parent',app.UIAxes);
                       imwrite(gri,'GriR.png');
                   case 'Gri resmi > Siyah Beyaz resme dönüştürme'
                       image2=imread('GriR.png');
                       doubles=im2double(image2);
                       [m,n]=size(image2);
                       for i=1:m;
                           for j=1:n;
                               if(doubles(i,j)>=0.5)
                                   siyah(i,j)=1;
                               else 
                                   siyah(i,j)=0;
                               end
                           end
                       end
                       app.UIAxes.Visible=true;
                       imshow(siyah,'Parent',app.UIAxes);
                       imwrite(siyah,'SiyahR.png');
                  case 'Zoom in – Zoom out'
                      image3=imread('SiyahR.png');
                      [m,n]=size(image3);
                      for i=1:2*m
                          for j=1:2*n
                              p=i-floor(i/2);
                              q=j-floor(j/2);
                              
                              zoominI(i,j)= image3(p,q);
                          end
                      end
                      app.UIAxes.Visible=true;
                      imshow(zoominI,'Parent',app.UIAxes);
                      imwrite(zoominI,'ZoominI.png');
                      [m1,n1]=size(zoominI);
                      for i=1:m1/2
                          for j=1:n1/2
                              p1=i*2;
                              q1=j*2;
                              
                              zoomoutI(i,j)= zoominI(p1,q1);
                          end
                      end
                      
                      imshow(zoomoutI,'Parent',app.UIAxes);
                      imwrite(zoomoutI,'ZoomoutI.png');
                      
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIOnemislemM = uifigure('Visible', 'off');
            app.UIOnemislemM.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIOnemislemM);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.UIOnemislemM);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.OnislemUygula = uiradiobutton(app.ButtonGroup);
            app.OnislemUygula.Text = 'Ön işleme Uygulamak istiyorum';
            app.OnislemeUygulama = uiradiobutton(app.ButtonGroup);
            app.OnislemeUygulama.Text = 'Ön işleme Uygulamak istemiyorum';
            app.OnislemeUygulama.Value = true;
            app.GeriButton = uibutton(app.UIOnemislemM, 'push');
            app.GeriButton.ButtonPushedFcn = createCallbackFcn(app, @GeriButtonPushed, true);
            app.GeriButton.Text = 'Geri';
            app.leriButton = uibutton(app.UIOnemislemM, 'push');
            app.leriButton.ButtonPushedFcn = createCallbackFcn(app, @leriButtonPushed, true);
            app.leriButton.Text = 'İleri';
            app.ONLEMMENUSU1DropDownLabel = uilabel(app.UIOnemislemM);
            app.ONLEMMENUSU1DropDownLabel.Text = 'ONİŞLEM MENUSU-1';
            app.ONLEMMENUSU1DropDown = uidropdown(app.UIOnemislemM);
            app.ONLEMMENUSU1DropDown.Items = {'Renkli resmi > Gri seviye resme dönüştürme', 'Gri resmi > Siyah Beyaz resme dönüştürme', 'Zoom in – Zoom out', 'Resimden istenilen bölgenin kesilip alınması'};
            app.ONLEMMENUSU1DropDown.DropDownOpeningFcn = createCallbackFcn(app, @ONLEMMENUSU1DropDownOpening, true);
            app.ONLEMMENUSU1DropDown.ValueChangedFcn = createCallbackFcn(app, @ONLEMMENUSU1DropDownValueChanged, true);
            app.ONLEMMENUSU1DropDown.Enable = 'off';
            app.ONLEMMENUSU1DropDown.Value = 'Zoom in – Zoom out';
            % Show the figure after all components are created
            app.UIOnemislemM.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OnislemMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIOnemislemM)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIOnemislemM)
        end
    end
end