classdef OnislemMenu2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        leriButton                      matlab.ui.control.Button
        GeriButton                      matlab.ui.control.Button
        ONLEMMENUSU2DropDown            matlab.ui.control.DropDown
        ONLEMMENUSU2DropDownLabel       matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        nilemeUygulamaistemiyorumButton  matlab.ui.control.RadioButton
        nilemeUygulamakistiyorumButton  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: leriButton
        function leriButtonPushed(app, event)
            FiltrelemeMenu
        end

        % Button pushed function: GeriButton
        function GeriButtonPushed(app, event)
            OnislemMenu
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.nilemeUygulamakistiyorumButton.Value==1
               app.ONLEMMENUSU2DropDown.Enable=true;
            elseif app.nilemeUygulamaistemiyorumButton.Value==1
               app.ONLEMMENUSU2DropDown.Enable=false;
            end
            clc
            clear all 
            close all
        end

        % Value changed function: ONLEMMENUSU2DropDown
        function ONLEMMENUSU2DropDownValueChanged(app, event)
            value = app.ONLEMMENUSU2DropDown.Value;
            switch value
                case 'Histogram oluşturma'
                    x=imread('GriR.png');
                    h=zeros(1,256);
                    [r c]=size(x);
                    n=0:255;
                    for i=1:r
                        for j=1:c
                            h(x(i,j)+1)=h(x(i,j)+1)+1;
                        end
                        stem(app.UIAxes,n,h);
                        drawnow limitrate;
                    end
                    app.UIAxes.Visible=true;
                    histogram(app.UIAxes,x);
                    %imwrite(hist,'histogramO.png');
                case 'Histogram Eşitleme'
                    img=imread('GriR.png');
                    max_r=size(img,1);
                    max_c=size(img,2);
                    hist=zeros([1 256]);
                    cumulative_hist= zeros([1 256]);
                    
                    for r=1:max_r
                        for c=1:max_c
                            for count=1:256
                                if(img(r,c) == count-1)
                                    hist(count)=hist(count)+1;
                                    break;
                                end
                            end
                        end
                    end
                    %find cumulative histogram
                    current_value=0;
                    for count=1:256
                        current_value=current_value+hist(count);
                        cumulative_hist(count)=current_value;
                    end
                    %find h=
                    %this is the normalized cumulative histogram
                    %histogram equalization formulu
                    normalized_hist=zeros([1 256]);
                    cdf_min=min(cumulative_hist);
                    for count=1:256
                        normalized_hist(count)=cumulative_hist(count) -cdf_min;
                        normalized_hist(count)=normalized_hist(count)/((max_r*max_c)-cdf_min);
                        normalized_hist(count)=round(normalized_hist(count)*255);
                        
                    end
                    %replace the value with the given equalized values
                    equalized_image=zeros(max_r,max_c);
                    for r=1:max_r
                        for c=1:max_c
                            for count=1:256
                                if(img(r,c)==(count-1))
                                    %we have got the value of intenasity
                                    %this with the equalized intensity
                                    equalized_image(r,c)=normalized_hist(count);
                                    break;
                                end
                                
                            end
                        end
                    end
                    app.UIAxes.Visible=true;
                    imwrite(equalized_image,'equalization.png');
                    H=uint8(equalized_image);
                    histogram(app.UIAxes,H);                 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.nilemeUygulamakistiyorumButton = uiradiobutton(app.ButtonGroup);
            app.nilemeUygulamakistiyorumButton.Text = 'Ön işleme Uygulamak istiyorum';
            app.nilemeUygulamaistemiyorumButton = uiradiobutton(app.ButtonGroup);
            app.nilemeUygulamaistemiyorumButton.Text = 'Ön işleme Uygulama istemiyorum';
            app.nilemeUygulamaistemiyorumButton.Value = true;
            app.ONLEMMENUSU2DropDownLabel = uilabel(app.UIFigure);
            app.ONLEMMENUSU2DropDownLabel.Text = 'ONİŞLEM MENUSU-2';
            app.ONLEMMENUSU2DropDown = uidropdown(app.UIFigure);
            app.ONLEMMENUSU2DropDown.Items = {'Histogram oluşturma', 'Histogram Eşitleme', 'Görüntü Nicemleme'};
            app.ONLEMMENUSU2DropDown.ValueChangedFcn = createCallbackFcn(app, @ONLEMMENUSU2DropDownValueChanged, true);
            app.ONLEMMENUSU2DropDown.Enable = 'off';
            app.ONLEMMENUSU2DropDown.Value = 'Histogram Eşitleme';
            app.GeriButton = uibutton(app.UIFigure, 'push');
            app.GeriButton.ButtonPushedFcn = createCallbackFcn(app, @GeriButtonPushed, true);
            app.GeriButton.Text = 'Geri';
            app.leriButton = uibutton(app.UIFigure, 'push');
            app.leriButton.ButtonPushedFcn = createCallbackFcn(app, @leriButtonPushed, true);
            app.leriButton.Text = 'İleri';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OnislemMenu2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end