classdef ResimAcma < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ResimAcButton    matlab.ui.control.Button
        AilanResimLabel  matlab.ui.control.Label
        LERButton        matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OnislemMenu % Description
    end
    
    methods (Access = public)
        
        function importdata(app,value)
            app.importeddatafromOnislemEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LERButton
        function LERButtonPushed(app, event)
            OnislemMenu
            clc
            clear all 
            close all
        end

        % Button pushed function: ResimAcButton
        function ResimAButtonPushed(app, event)
            global image1;
            [FileName,PathName]= uigetfile({'*.jpg;*.bmp;*.png','İmage';},'open File');
            fullpathname= strcat(PathName,FileName);
            image1= imread(fullpathname);
            app.UIAxes.Visible=true;
            imshow(image1,'parent',app.UIAxes);
            imwrite(image1,'Resim.png'); %save
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes.Tag = 'axes1';
            app.LERButton = uibutton(app.UIFigure, 'push');
            app.LERButton.ButtonPushedFcn = createCallbackFcn(app, @LERButtonPushed, true);
            app.LERButton.Text = 'İLERİ';
            app.AilanResimLabel = uilabel(app.UIFigure);
            app.AilanResimLabel.Text = 'Açilan Resim:';
            app.ResimAcButton = uibutton(app.UIFigure, 'push');
            app.ResimAcButton.ButtonPushedFcn = createCallbackFcn(app, @ResimAButtonPushed, true);
            app.ResimAcButton.Text = 'Resim Ac';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResimAcma

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end